classdef mmSliceView < matlab.apps.AppBase
    %MMSLICEVIEW Displays slices from a 3D image volume
    %   A volumetric viewer tool that improves on the built-in  MATLAB
    %   sliceViewer by allowing the user to scrub through slices using the
    %   mouse scroll wheel (as God intended). mmSliceView also users to
    %   overlay a mask on the volume, so users can inspect the effectiness
    %   of the mask, slice by slice (again, as relayed from on high). 
    %   
    %   Inputs: mmSliceView is smart enougth to accept either 1 or 2 inputs
    %       - Structure: 1 input only. Must contain fields that have 3D
    %   volumes
    %       - Numeric: 1 or 2 inputs. The first input should be a numeric
    %   3D volume. The second input can be a numeric input or a logical
    %   mask.
    %   Dimensions must match between the volume and the mask
    %   
    %   EXAMPLE
    %   mmSliceView(S)
    %   mmSliceView(vol1, mask1)
    %   mmSliceView(vol1, vol2)
    %   
    %   ---
    %   Author: Ernesto Salcedo, PhD
    %   Site: University of Colorado Modern Human Anatomy
    %   Updated: Jan 06, 2024

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        VolumeMenu             matlab.ui.container.Menu
        AdjustContrastMenu     matlab.ui.container.Menu
        UpdateVolumeMenu       matlab.ui.container.Menu
        ColormapsMenu          matlab.ui.container.Menu
        grayMenu               matlab.ui.container.Menu
        parulaMenu             matlab.ui.container.Menu
        turboMenu              matlab.ui.container.Menu
        hotMenu                matlab.ui.container.Menu
        ChSelectEditField      matlab.ui.control.EditField
        OverlayButton          matlab.ui.control.StateButton
        MaskButton             matlab.ui.control.StateButton
        ColormapDropDown       matlab.ui.control.DropDown
        ColormapDropDownLabel  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        ChannelSpinner         matlab.ui.control.Spinner
        ChannelSpinnerLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        Vol % N Dimension Volume
        Mask % Binary Volume, same size as Vol
        VolName % name if included
        MaskName % name if included
        VarName % name of workspace variable
        Ch2Disp % 3 values
    end
    
    properties (Access = private)
        volSize % Dimensions of Vol and Mask
        volCurrentSlcIdx % Index of current z-value
        volTotalSlice % total number of z-slices
        chSelected % selected channel index
        hImg % handle to image
        hAx
        cMap % selected colormap
        
    end
    
    methods (Access = private)
        
        % INITIALIZE DISPLAY OF CURRENT SLICE
        function himg = InitImg(app,vol_name)
            % referred to this page: https://www.mathworks.com/matlabcentral/answers/360670-imshow-in-app-designer-image-size-doesn-t-fit            
            % himage = imshow(img,Parent=app.UIAxes,Border="tight");

            img = getVolSlice(app,vol_name);

            if ~isnumeric(img)
                img = single(img);
            end

            himg = imshow(img, Parent = app.hAx);
        
            impixelinfo(app.Panel)

            app.hAx.Units = "normalized";
            app.hAx.Position = [0 0 1 1];

            % Set limits of axes
            app.hAx.XLim = [0 himg.XData(2)];
            app.hAx.YLim = [0 himg.YData(2)];

            set(app.UIFigure,'Name',sprintf('Vol: %s, mask: %s | %d | %d',app.VolName,app.MaskName,app.volCurrentSlcIdx,app.volTotalSlice))
        end
        
        % KEEP Z-INDEX WITHIN RANGE 
        function idx = SliceIdxRollover(app,idx)
              % Roll over control
            if idx > app.volTotalSlice
                % If index exceeds number of slices in stack, go to first slice
                idx = 1;
            elseif idx < 1
                % if index drops below 1, go to the last slice
                idx = app.volTotalSlice;
            end
        end
        
        %UPDATE DISPLAY OF SLICE 
        function ImageUpdate(app)

            % get slice to display
            if app.MaskButton.Value
                msk = getVolSlice(app,'Mask');
                if app.OverlayButton.Value && (numel(app.volSize)==3 || app.ChannelSpinner.Value) % blend mask if single channels 
                    img = getVolSlice(app,'Vol');                    
                    img = imfuse(img, msk);
                else
                    img = single(msk); % convert mask to single (for display purposes)
                end
            else % just show volume
                img = getVolSlice(app,'Vol');               
            end

            set(app.hImg,CData = img);

            set(app.UIFigure,'Name',sprintf('Vol: %s, mask: %s | %d | %d',app.VolName, app.MaskName, app.volCurrentSlcIdx,app.volTotalSlice))
        end

        % RETURNS SLICE FROM VOLUME
        function slc = getVolSlice(app,vol_name)
        % indexes out a slice from a Volume
        % vol_name: either 'Vol' or 'Mask'

            if ndims(app.(vol_name))>3
                if app.ChannelSpinner.Value
                    slc = app.(vol_name)(:,:,app.ChannelSpinner.Value,app.volCurrentSlcIdx); % get channel
                else
                    slc = app.(vol_name)(:,:,app.Ch2Disp,app.volCurrentSlcIdx); % show all channels
                end
            else
                slc = app.(vol_name)(:,:,app.volCurrentSlcIdx);
            end

            if ~ismatrix(slc)
                if size(slc,3)>3
                    slc = slc(:,:,1:3);
                elseif size(slc,3)==2
                    slc = imfuse(slc(:,:,1),slc(:,:,2),ColorChannels="red-cyan");
                end
            end

            % adjust contrast as needed
            if app.AdjustContrastMenu.Checked && isnumeric(slc)
                if ismatrix(slc)
                    slc = imadjust(slc);
                elseif size(slc,3)==3
                    lims = stretchlim(slc);
                    slc = imadjust(slc,lims,[]);
                end
            end

        end

        
        % RETURNS VARIABLE NAME FROM WORKSPACE
        function VarName = getVarname(~,wsVars)
            varnames = string({wsVars.name})';
            varclass = string({wsVars.class})';

            varnames = varnames(varclass=='struct'); % only structures

            [idx,ok] = listdlg("PromptString","Which Structure Variable?",...
                "SelectionMode","single",...
                "ListString",varnames);

            if ok
                % S = evalin("base",varnames(idx));
                VarName = varnames(idx);
            else
                VarName = " ";
            end
            
        end
        
        % PROCESS STRUCTURE INPUTS
        function properDims = processStructInput(app,S)
            fn = fieldnames(S); % get field names
            la = (structfun(@isnumeric,S) | structfun(@islogical,S)) & ~structfun(@ismatrix,S); % checks for fields that contain volumes that are numeric or logical (not matrices)
            properDims = any(la); % at least one field must contain a 3D volume

            if properDims % structure must contain at least 1 field with a 3D volume
                fn = fn(la);
                [idx,ok] = listdlg('PromptString','Select Volume','ListString',fn);

                if ok % if user selects OK
                    app.Vol = S.(fn{idx(1)}); % get volume from first field selected
                    app.VolName = fn{idx(1)}; % get field name of first volume
                    if numel(idx)>1 % if more than one selected, mask is second selection
                        app.Mask = S.(fn{idx(2)}); % get volume from second field selected
                        app.MaskName = fn{idx(2)};
                        % app.MaskButton.Enable = "on";
                    else
                        app.Mask = false(size(app.Vol)); % initialize mask
                    end
                else % user cancels list dialog
                    properDims = false; % cancel load
                end
            else
                properDims = false; % improper structure (with no 3D volumes) loaded)
            end
        end
    end
    
    methods (Access = public)
        
        function updateVolume(app)
            wsVars = evalin('base','whos'); % get list of workspace variables
            wsVarNames = string({wsVars.name});

            if isempty(app.VarName)
                app.VarName = getVarname(app,wsVars);
            end

            if any(wsVarNames == app.VarName)
                V = evalin("base",app.VarName); % workspace match found
            else
                beep
                fprintf('Variable %s not found in workspace or user canceled operation.\n',app.VarName)
                return
            end

            if isstruct(V) % updating structure variables
                fn = string(fieldnames(V));

                if any(fn==app.VolName)
                    app.Vol = V.(app.VolName);
                    fprintf('%s updated.\n',app.VolName)
                end

                if  any(fn==app.MaskName)
                    app.Mask = V.(app.MaskName);
                    fprintf('%s updated.\n',app.MaskName)
                end
            elseif isequal(size(V),app.volSize) % updating 3D volumes
                app.Vol = V;
                fprintf('%s updated.\n',app.VolName)

                if any(wsVarNames==app.MaskName) && isequal(app.volSize,wsVars(wsVarNames==app.MaskName).size)
                    app.Mask = evalin("base",app.MaskName); % workspace match found
                    fprintf('%s updated.\n',app.MaskName);
                end
            else
                disp('No updates possible.')
            end
        end
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            properDims = true; % not a matrix (dimensions greater than 2D)
            proper_inputs = true;
            set_VolName = true;
            app.VolName = '+';
            app.MaskName = '-'; % default is no mask
            app.Ch2Disp = [1 2 3];
            % app.MaskButton.Enable = "off";

            
            if isempty(varargin) % if no variables inputted, prompt for ws variables
                wsVars = evalin('base','whos'); % get list of workspace variables
                wsVarNames = string({wsVars.name});

                [idx,ok] = listdlg('PromptString','Select workspace Volume','ListString',wsVarNames);

                if ~ok || numel(idx)>2
                    beep; disp('load canceled');
                    delete(app)
                    return
                else
                    for n=1:numel(idx)
                        VolIn(n) = {evalin("base",wsVarNames(idx(n)))};
                    end
                end
            else
                VolIn = varargin; % otherwise use inputed variables
            end
            
            if isscalar(VolIn) && isstruct(VolIn{1}) % if input is a struct
                S = VolIn{1};
                properDims = processStructInput(app,S);
                set_VolName = false; % don't use inputname for volume names (doesn't work for structures)
      
            elseif isscalar(VolIn) && (isnumeric(VolIn{1}) | islogical(VolIn{1})) % input = a single 3D volume or Mask
                app.Vol = VolIn{1};
                properDims = ~ismatrix(app.Vol); % not 2D
                app.Mask = false(size(app.Vol));
            elseif numel(VolIn)==2 % if two inputs, must be two volumes (numeric or logical)
                app.Vol = VolIn{1}; % get input 1
                app.Mask = VolIn{2}; % get input 2
                app.MaskName = '+'; % update for second volume

                % app.MaskButton.Enable = "on";
                app.MaskButton.Value = true;
                app.OverlayButton.Value = true;

                properDims = ~ismatrix(app.Vol) & size(app.Vol)==size(app.Mask); % not 2D and dimensions match
            else
                beep
                proper_inputs = false;
            end

            properDims = properDims & ndims(app.Vol)<5; % 3D and 4D volumes only

            if ~(properDims & proper_inputs)
                beep
                fprintf('Slice View Canceled.\nAt least 1 input required. Maximum of 2 inputs supported.\nIf 1 input, must be Structure or 3D Volume.\nIf 2 inputs, Must be 3D vol and/or 3D mask of same size.\n')

                % disp('First input must have dimensions greater than 2')
                delete(app)
                return
            end

            if set_VolName
                vn = inputname(2);
                if ~isempty(vn)
                    app.VolName = vn;
                end

                if numel(varargin)==2 && ~isempty(inputname(2))
                    mn = inputname(3);
                    app.MaskName = mn;                    
                end
            end

            % set volume and props
            app.volSize = size(app.Vol);
            
             % get number of slices
            if ndims(app.Vol) > 3 
                app.volTotalSlice = size(app.Vol,4);

                if size(app.Vol,3) == 2
                    app.Ch2Disp = [1 2]; % 2 channel image stacks
                end

                % Initial Channel controls
                app.ChannelSpinner.Enable = "on";                
                app.ChannelSpinner.Limits = [0 size(app.Vol,3)];
                app.ChannelSpinner.Visible = "on";

                app.ChSelectEditField.Value = num2str(app.Ch2Disp);
                app.ChSelectEditField.Visible = "on";
                app.ChSelectEditField.Enable = "on";
            else
                app.volTotalSlice = size(app.Vol,ndims(app.Vol));                
            end

            app.volCurrentSlcIdx = round(app.volTotalSlice/2); % start at middle slice
            app.cMap = "gray";

            aspectRatio = app.volSize(1)/app.volSize(2);
            app.UIFigure.Position(4) = app.UIFigure.Position(3) * aspectRatio;

            app.hAx = uiaxes(Parent=app.Panel);
            app.hImg = InitImg(app,'Vol');

        end

        % Window scroll wheel function: UIFigure
        function UIscross_process(app, event)
            % verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            idx = app.volCurrentSlcIdx + verticalScrollCount;
            idx = SliceIdxRollover(app,idx);
            app.volCurrentSlcIdx = idx;
            ImageUpdate(app)
                   
        end

        % Menu selected function: AdjustContrastMenu
        function MenuToggleCheck(app, event)
            if event.Source.Checked
                set(event.Source,'Checked','off')
            else
                if isnumeric(app.Vol)
                    set(event.Source,'Checked','on')
                end
            end          
            ImageUpdate(app)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
          
            switch event.Key
                case 'downarrow'
                    idx = app.volCurrentSlcIdx - 1;
                    idx = SliceIdxRollover(app,idx);
                    app.volCurrentSlcIdx = idx;
                    ImageUpdate(app)
                case 'uparrow'
                    idx = app.volCurrentSlcIdx + 1;
                    idx = SliceIdxRollover(app,idx);
                    app.volCurrentSlcIdx = idx;
                    ImageUpdate(app)
            end                    
        end

        % Menu selected function: UpdateVolumeMenu
        function UpdateVolumeMenuSelected(app, event)
            updateVolume(app)
        end

        % Menu selected function: grayMenu, hotMenu, parulaMenu, turboMenu
        function ColormapMenuSelected(app, event)
            set(app.ColormapsMenu.Children,Checked="off");
            event.Source.Checked = "on";
            app.cMap = event.Source.Text;
            colormap(app.UIFigure,event.Source.Text);
        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            % value = app.ChannelSpinner.Value;
            if app.ChannelSpinner.Value
                app.ChSelectEditField.Enable = "off";
            else
                app.ChSelectEditField.Enable = "on";
            end

            app.hImg = InitImg(app,'Vol');
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            app.cMap = value;
            colormap(app.UIFigure,value);
        end

        % Value changed function: MaskButton
        function ButtonValueChanged(app, event)
            app.hImg = InitImg(app,'Mask');
        end

        % Value changed function: ChSelectEditField
        function ChSelectEditFieldValueChanged(app, event)
            value = app.ChSelectEditField.Value;
            ch2disp = uint8(str2num(value));
            num_ch = size(app.Vol,3);

            ch2disp(ch2disp>num_ch) = [];
            ch2disp(ch2disp<1) = [];
            app.ChSelectEditField.Value = num2str(ch2disp);
            app.Ch2Disp = ch2disp;
            ImageUpdate(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIscross_process, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.VolumeMenu = uimenu(app.UIFigure);
            app.VolumeMenu.Text = 'Volume';
            app.AdjustContrastMenu = uimenu(app.VolumeMenu);
            app.AdjustContrastMenu.MenuSelectedFcn = createCallbackFcn(app, @MenuToggleCheck, true);
            app.AdjustContrastMenu.Accelerator = 'a';
            app.AdjustContrastMenu.Text = 'Adjust Contrast';
            app.UpdateVolumeMenu = uimenu(app.VolumeMenu);
            app.UpdateVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateVolumeMenuSelected, true);
            app.UpdateVolumeMenu.Accelerator = 'u';
            app.UpdateVolumeMenu.Text = 'Update Volume';
            app.ColormapsMenu = uimenu(app.VolumeMenu);
            app.ColormapsMenu.Text = 'Colormaps';
            app.grayMenu = uimenu(app.ColormapsMenu);
            app.grayMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.grayMenu.Text = 'gray';
            app.parulaMenu = uimenu(app.ColormapsMenu);
            app.parulaMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.parulaMenu.Text = 'parula';
            app.turboMenu = uimenu(app.ColormapsMenu);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.hotMenu = uimenu(app.ColormapsMenu);
            app.hotMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.hotMenu.Text = 'hot';
            app.ChannelSpinnerLabel = uilabel(app.UIFigure);
            app.ChannelSpinnerLabel.Enable = 'off';
            app.ChannelSpinnerLabel.Visible = 'off';
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.UIFigure);
            app.ChannelSpinner.Limits = [0 3];
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Enable = 'off';
            app.ChannelSpinner.Visible = 'off';
            app.ChannelSpinner.Tooltip = {'Set to 0 for all channels'};
            app.Panel = uipanel(app.UIFigure);
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'Gray', 'Parula', 'Turbo', 'Hot'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Gray';
            app.MaskButton = uibutton(app.UIFigure, 'state');
            app.MaskButton.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.MaskButton.Text = 'Mask';
            app.OverlayButton = uibutton(app.UIFigure, 'state');
            app.OverlayButton.Text = 'Overlay';
            app.ChSelectEditField = uieditfield(app.UIFigure, 'text');
            app.ChSelectEditField.ValueChangedFcn = createCallbackFcn(app, @ChSelectEditFieldValueChanged, true);
            app.ChSelectEditField.Enable = 'off';
            app.ChSelectEditField.Visible = 'off';
            app.ChSelectEditField.Tooltip = {'Enter channels to display, separated by spaces'};
            app.ChSelectEditField.Value = '1 2 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mmSliceView(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end