classdef dlgSliceVolSelect < matlab.apps.AppBase
    %DLGSLICEVOLSELECT support app for mmSliceView and mmSliceCompare
    %   Dialog app that allows user to select different volumes for
    %   mmSliceView and/or mmSliceCompare

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Volume2ListBoxLabel  matlab.ui.control.Label
        SelectVolumeLabel    matlab.ui.control.Label
        Volume1ListBox       matlab.ui.control.ListBox
        Volume1ListBoxLabel  matlab.ui.control.Label
        Volume2ListBox       matlab.ui.control.ListBox
        Button               matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp % Main app
        PreviouslySelected % structure containing previously selected fieldnames
        S % input structure of Volumes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, caller, varargin)
            % Store main app in property for CloseRequestFcn to use

            app.CallingApp = caller;

            if nargin>2 && ~isempty(varargin{1}) && isstruct(varargin{1})
                app.S = varargin{1}; % if Structure inputed, assign to S
            else % find structure in workspace
                wsVars = evalin('base','whos');

                if any(strcmp({wsVars.name},caller.VarName))
                    app.S = evalin("base",caller.VarName); % workspace match found
                else % ask for workspace variables that are structures
                    varnames = string({wsVars.name})';
                    varclass = string({wsVars.class})';

                    varnames = varnames(varclass=='struct'); % only structures

                    [idx,ok] = listdlg("PromptString","Which Structure Variable?",...
                        "SelectionMode","single",...
                        "ListString",varnames);

                    if ok
                        app.S = evalin("base",varnames(idx));
                        caller.VarName = varnames(idx);
                    else
                        disp('Selection Canceled')
                        delete(app)
                        return
                    end
                end
            end
            
            fn = fieldnames(app.S); % get structure fieldnames

            app.Volume1ListBox.Items = fn;
            app.Volume2ListBox.Items = fn;


            switch class(caller)
                case 'mmSliceView'
                    app.PreviouslySelected = [caller.VolName caller.MaskName];  
                    app.Volume1ListBox.Value = fn{1};
                    app.Volume2ListBox.Value = {}; % default to just one volume

                case 'mmSliceCompare'
                    app.PreviouslySelected = [caller.VolName1.Value caller.VolName2.Value];

                    if any(strcmp(fn,caller.VolName1.Value))
                        app.Volume1ListBox.Value = caller.VolName1.Value;
                    end

                    if any(strcmp(fn,caller.VolName2.Value))
                        app.Volume2ListBox.Value = caller.VolName2.Value;
                    end              
            end
          
            figure(app.UIFigure)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function

            volname1 = app.Volume1ListBox.Value; % selected name
            vol1 = app.S.(volname1); % get volume 1

            volname2 = app.Volume2ListBox.Value;
            
            if ~isempty(volname2)
                vol2 = app.S.(app.Volume2ListBox.Value); % get volume 2
            else
                vol2 = [];
            end

            switch class(app.CallingApp)
                case 'mmSliceView'
                    app.CallingApp.Vol = vol1;
                    app.CallingApp.VolName = volname1;

                    if isequal(size(vol2),size(vol1))
                        app.CallingApp.Mask = vol2;
                        app.CallingApp.MaskName = volname2; % selected mask name
                    else
                        app.CallingApp.Mask = false(size(vol1));
                        app.CallingApp.MaskName = '-';
                    end
                case 'mmSliceCompare'
                    callVol = app.CallingApp.Vol1;
                    if isequal(size(vol1),size(vol1),size(callVol)) % make sure dimensions match
                        app.CallingApp.Vol1 = vol1; %update volume
                        app.CallingApp.VolName1.Value = volname1; % update text box

                        app.CallingApp.Vol2 = vol2; %update volume
                        app.CallingApp.VolName2.Value = volname2; % update text box
                    else
                        fprintf('Volumes selected do not match dimensions of previously selected volumes.')
                    end
            end

            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Options button in main app, if the app is
            % still open
            if isvalid(app.CallingApp)
                app.CallingApp.OptionsButton.Enable = "on";
            end
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Select Volume';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'UPDATE';
            app.Volume2ListBox = uilistbox(app.UIFigure);
            app.Volume2ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5'};
            app.Volume2ListBox.Tooltip = {'Volume 2'};
            app.Volume1ListBoxLabel = uilabel(app.UIFigure);
            app.Volume1ListBoxLabel.Text = 'Volume 1';
            app.Volume1ListBox = uilistbox(app.UIFigure);
            app.Volume1ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5'};
            app.Volume1ListBox.Tooltip = {'Volume 1'};
            app.SelectVolumeLabel = uilabel(app.UIFigure);
            app.SelectVolumeLabel.Text = 'Select Volume';
            app.Volume2ListBoxLabel = uilabel(app.UIFigure);
            app.Volume2ListBoxLabel.Text = 'Volume 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dlgSliceVolSelect(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end