classdef mmSliceCompare < matlab.apps.AppBase
    %MMSLICECOMPARE Display slices from two 3D volumes, side by side
    %   This simple slice viewer utility was built on the simple concept
    %   that two is better than one. With this utility, you can load two 3D
    %   image volumes (of the same dimensions) and view the corresponding
    %   sections from each volume, side by side (as God intended). Feel
    %   free to scrub through slices using the mouse scroll wheel, which is
    %   always a blast. The slice displays are linked, so if you zoom in on
    %   one image slice, the other image will zoom in as well. Be sure to
    %   un-select the zooming tool once you would like to start scrubbing
    %   through the slices again
    %   
    %   As you call this function, keep the following in mind:
    %   
    %   You can have as many inputs as you would like, as long as the
    %   number of these inputs adds up to 1 or 2. 
    %   
    %   If you have 1 input, make sure that input is a structure,and that
    %   at least two fields in that structure contain 3D volumes. 
    %   
    %   If you have TWO inputs, make sure those inputs are numeric, are 3D,
    %   and have matching dimensions. 
    %   
    %   
    %   EXAMPLES:
    %   mmSliceCompare(S) — where S means structure
    %   mmSliceCompare(vol1, vol2) - vol1 and vol2 must have matching
    %   dimensions. 
    %   
    %   ---
    %   Author: Ernesto Salcedo, PhD
    %   Site: University of Colorado Modern Human Anatomy
    %   Updated: Jan 06, 2024

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VolMenu                  matlab.ui.container.Menu
        EnhanceContrastMenu      matlab.ui.container.Menu
        ChangeVolumeMenu         matlab.ui.container.Menu
        RepositionPixelInfoMenu  matlab.ui.container.Menu
        ColormapMenu             matlab.ui.container.Menu
        grayMenu                 matlab.ui.container.Menu
        parulaMenu               matlab.ui.container.Menu
        turboMenu                matlab.ui.container.Menu
        jetMenu                  matlab.ui.container.Menu
        hsvMenu                  matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        VolName1                 matlab.ui.control.EditField
        EditFieldLabel           matlab.ui.control.Label
        VolName2                 matlab.ui.control.EditField
        Ax2                      matlab.ui.control.UIAxes
        Ax1                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Vol1 % N Dimension Volume
        Vol2 % N Dimension Volume
        VarName % name of Variable Structure
    end
    
    properties (Access = private)
        VolSize % Dimensions of Vol and Mask
        VolCurrentSlcIdx % Index of current z-value
        VolTotalSlice % total number of z-slices
        hImg1 % handle to image
        hImg2
        UIAxes
        hPixInfo
        % sVolName1 % selected volume name 1 
        % sVolName2 % selected volumen name 2
    end
    
    methods (Access = private)
        
        function SliceInit(app)
            % referred to this page: https://www.mathworks.com/matlabcentral/answers/360670-imshow-in-app-designer-image-size-doesn-t-fit

            slice1 = app.Vol1(:,:,app.VolCurrentSlcIdx);
            slice2 = app.Vol2(:,:,app.VolCurrentSlcIdx);

            img(:,:,1) = slice1;
            img(:,:,2) = slice2;


            for n=1:2
                app.(sprintf('hImg%d',n)) = imshow(img(:,:,n), Parent = app.(sprintf('Ax%d',n)));

                % Set limits of axes
                app.(sprintf('Ax%d',n)).XLim = [0 app.(sprintf('hImg%d',n)).XData(2)];
                app.(sprintf('Ax%d',n)).YLim = [0 app.(sprintf('hImg%d',n)).YData(2)];
            end
            
            linkaxes([app.Ax1 app.Ax2],'xy')

            set(app.UIFigure,'Name',sprintf('%d | %d',app.VolCurrentSlcIdx,app.VolTotalSlice))
            app.hPixInfo = impixelinfo(app.UIFigure);
            % set(app.hPixInfo,'Units','Normalized','Position',[0.01 0.01 .2 .06])
          
        end
        
        function idx = SliceIdxRollover(app,idx)
              % Roll over control
            if idx > app.VolTotalSlice
                % If index exceeds number of slices in stack, go to first slice
                idx = 1;
            elseif idx < 1
                % if index drops below 1, go to the last slice
                idx = app.VolTotalSlice;
            end
        end

        function SliceUpdate(app)

            % get slices to display
            slc1 = app.Vol1(:,:,app.VolCurrentSlcIdx);
            slc2 = app.Vol2(:,:,app.VolCurrentSlcIdx);

            % adjust contrast
            if app.EnhanceContrastMenu.Checked
                slc1 = imadjust(slc1);
                slc2 = imadjust(slc2);
            end

            set(app.hImg1,CData = slc1);
            set(app.hImg2,CData = slc2);
            set(app.UIFigure,'Name',sprintf('%d | %d',app.VolCurrentSlcIdx,app.VolTotalSlice))
        end
        
        
        function ok = selectVolumes(app,S)
            fn = fieldnames(S);

            idx = 0;
            ok = true;

            while numel(idx)~=2 & ok
                [idx,ok] = listdlg("PromptString","Which Volumes (Choose 2",...
                    "SelectionMode","multiple",...
                    "ListString",fn);
            end
            if ~ok
                disp('selection canceled.')
                return
            else
                app.Vol1 = S.(fn{idx(1)});
                app.Vol2 = S.(fn{idx(2)});
                app.VolName1.Value = fn{idx(1)};
                app.VolName2.Value = fn{idx(2)};
            end

            figure(app.UIFigure)
        end
        
    end
    
    methods (Access = public)
        
        function changeVolume(app,varargin)

            if isempty(varargin)
                dlgSliceVolSelect(app)
            else
                dlgSliceVolSelect(app,varargin{1})
            end            
        end                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            if numel(varargin)==1 && isstruct(varargin{1})

                S = varargin{1};
                fn = fieldnames(S);
                la = structfun(@isnumeric,S) & ~structfun(@ismatrix,S); % only numeric, non-matrix volumes

                S = rmfield(S,fn(~la)); % remove non-volume fields
                fn = fieldnames(S); % get fieldnames of adjusted structure

                app.Vol1 = S.(fn{1});
                app.Vol2 = S.(fn{numel(fn)});

                VNs = fn([1 numel(fn)]); % volume names                
                
            elseif numel(varargin)==2
                app.Vol1 = varargin{1};
                app.Vol2 = varargin{2};
                VNs = {'Volume 1' 'Volume 2'}; % volume names
            else
                fprintf('Remember, you need at least 1 input.\nAnd if you have 1 input, then that input better be a structure.\nIf you have two inputs, then those inputs must be 3D volumes\n and their dimensions better match.\n');
                delete(app)
                return
            end
        
            if all(size(app.Vol2) ~= size(app.Vol1)) || ismatrix(app.Vol1)
                beep
                disp('Input Sizes must match and must be 3D')
                delete(app)
                return
            end

            app.VolSize = size(app.Vol1);
            % app.VolName = sprintf('%s v %s',inputname(2),inputname(3));
            app.VolTotalSlice = size(app.Vol1,ndims(app.Vol1)); % get number of slices
            app.VolCurrentSlcIdx = round(app.VolTotalSlice/2); % update for color image

            SliceInit(app);
            app.VolName1.Value = VNs{1};
            app.VolName2.Value = VNs{2};
            app.VarName = '$NotSet';

        end

        % Window scroll wheel function: UIFigure
        function UIscross_process(app, event)
            % verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            idx = app.VolCurrentSlcIdx + verticalScrollCount;
            idx = SliceIdxRollover(app,idx);
            app.VolCurrentSlcIdx = idx;
            SliceUpdate(app)
                   
        end

        % Menu selected function: EnhanceContrastMenu
        function MenuToggleCheck(app, event)
            if event.Source.Checked
                set(event.Source,'Checked','off')
            else
                set(event.Source,'Checked','on')
            end          
            SliceUpdate(app)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            switch event.Key
                case 'downarrow'
                    idx = app.VolCurrentSlcIdx - 1;
                    idx = SliceIdxRollover(app,idx);
                    app.VolCurrentSlcIdx = idx;
                    SliceUpdate(app)
                case 'uparrow'
                    idx = app.VolCurrentSlcIdx + 1;
                    idx = SliceIdxRollover(app,idx);
                    app.VolCurrentSlcIdx = idx;
                    SliceUpdate(app)
            end                      
        end

        % Menu selected function: ChangeVolumeMenu
        function ChangeVolumeMenuSelected(app, event)
            dlgSliceVolSelect(app)
        end

        % Value changed function: VolName1, VolName2
        function VolNameValueChanged(app, event)
            % value = app.VolName1.Value;
            SliceUpdate(app)            
        end

        % Menu selected function: RepositionPixelInfoMenu
        function RepositionPixelInfoMenuSelected(app, event)
            delete(app.hPixInfo)
            app.hPixInfo = impixelinfo(app.UIFigure);
        end

        % Menu selected function: grayMenu, hsvMenu, jetMenu, parulaMenu, 
        % ...and 1 other component
        function ColorMapMenuSelected(app, event)
            
           cm = event.Source.Text;
           colormap(app.UIFigure, cm)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIscross_process, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.VolMenu = uimenu(app.UIFigure);
            app.VolMenu.Text = 'Vol';
            app.EnhanceContrastMenu = uimenu(app.VolMenu);
            app.EnhanceContrastMenu.MenuSelectedFcn = createCallbackFcn(app, @MenuToggleCheck, true);
            app.EnhanceContrastMenu.Accelerator = 'e';
            app.EnhanceContrastMenu.Text = 'Adjust Contrast';
            app.ChangeVolumeMenu = uimenu(app.VolMenu);
            app.ChangeVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeVolumeMenuSelected, true);
            app.ChangeVolumeMenu.Accelerator = 'd';
            app.ChangeVolumeMenu.Text = 'Change Volume';
            app.RepositionPixelInfoMenu = uimenu(app.VolMenu);
            app.RepositionPixelInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @RepositionPixelInfoMenuSelected, true);
            app.RepositionPixelInfoMenu.Accelerator = 'i';
            app.RepositionPixelInfoMenu.Text = 'Reposition Pixel Info';
            app.ColormapMenu = uimenu(app.UIFigure);
            app.ColormapMenu.Text = 'Colormap';
            app.grayMenu = uimenu(app.ColormapMenu);
            app.grayMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMapMenuSelected, true);
            app.grayMenu.Text = 'gray';
            app.parulaMenu = uimenu(app.ColormapMenu);
            app.parulaMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMapMenuSelected, true);
            app.parulaMenu.Text = 'parula';
            app.turboMenu = uimenu(app.ColormapMenu);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMapMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.jetMenu = uimenu(app.ColormapMenu);
            app.jetMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMapMenuSelected, true);
            app.jetMenu.Text = 'jet';
            app.hsvMenu = uimenu(app.ColormapMenu);
            app.hsvMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMapMenuSelected, true);
            app.hsvMenu.Text = 'hsv';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Ax1 = uiaxes(app.GridLayout);
            app.Ax2 = uiaxes(app.GridLayout);
            app.VolName2 = uieditfield(app.GridLayout, 'text');
            app.VolName2.ValueChangedFcn = createCallbackFcn(app, @VolNameValueChanged, true);
            app.VolName2.Value = 'VOLUME 2';
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.VolName1 = uieditfield(app.GridLayout, 'text');
            app.VolName1.ValueChangedFcn = createCallbackFcn(app, @VolNameValueChanged, true);
            app.VolName1.Value = 'VOLUME 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mmSliceCompare(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end